classdef assignment_finall2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tasks1and2Tab               matlab.ui.container.Tab
        SiemensLabel                matlab.ui.control.Label
        DE                          matlab.ui.control.EditField
        BE                          matlab.ui.control.EditField
        CE                          matlab.ui.control.EditField
        AE                          matlab.ui.control.EditField
        TL_model_T                  matlab.ui.control.Label
        TL_model_Pi                 matlab.ui.control.Label
        TL_model_S                  matlab.ui.control.Label
        DOCALCULATIONSButton        matlab.ui.control.Button
        TorPi                       matlab.ui.control.DropDown
        ChooseTheModelLabel_2       matlab.ui.control.Label
        Label_11                    matlab.ui.control.Label
        CalculationsofTLparametersLabel_2  matlab.ui.control.Label
        HmLabel                     matlab.ui.control.Label
        FmLabel                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Rtotal                      matlab.ui.control.NumericEditField
        L_phase                     matlab.ui.control.NumericEditField
        C_phase                     matlab.ui.control.NumericEditField
        InductanceLLabel            matlab.ui.control.Label
        CapacitanceCLabel           matlab.ui.control.Label
        ResistanceR_ACLabel         matlab.ui.control.Label
        ResultsLabel                matlab.ui.control.Label
        mLabel_6                    matlab.ui.control.Label
        mLabel_5                    matlab.ui.control.Label
        mLabel_4                    matlab.ui.control.Label
        kmLabel                     matlab.ui.control.Label
        cmLabel                     matlab.ui.control.Label
        mLabel                      matlab.ui.control.Label
        Dab                         matlab.ui.control.NumericEditField
        Dbc                         matlab.ui.control.NumericEditField
        Dac                         matlab.ui.control.NumericEditField
        len                         matlab.ui.control.NumericEditField
        d                           matlab.ui.control.NumericEditField
        rho                         matlab.ui.control.NumericEditField
        EntertheresistivitydiameterandlengthLabel  matlab.ui.control.Label
        D_bcLabel                   matlab.ui.control.Label
        D_abLabel                   matlab.ui.control.Label
        EnterthedistancebetweenphasesLabel  matlab.ui.control.Label
        ConductorLengthLLabel       matlab.ui.control.Label
        ConductorDiameterDLabel     matlab.ui.control.Label
        D_acLabel                   matlab.ui.control.Label
        ConductorresistivitypLabel  matlab.ui.control.Label
        symm_unsymm                 matlab.ui.control.DropDown
        CALCULATIONSOFRL_phC_phANDTLPARAMETERSABCDLabel  matlab.ui.control.Label
        Task3_Case1Tab              matlab.ui.container.Tab
        CALCANDDRAWButton31         matlab.ui.control.Button
        rv                          matlab.ui.control.NumericEditField
        powerf                      matlab.ui.control.NumericEditField
        powerr                      matlab.ui.control.NumericEditField
        PowerFactorLabel31          matlab.ui.control.Label
        RecievingVoltageLabel31     matlab.ui.control.Label
        KVLabel                     matlab.ui.control.Label
        KWLabel                     matlab.ui.control.Label
        PowerLabel31                matlab.ui.control.Label
        LabelT31                    matlab.ui.control.Label
        vp                          matlab.ui.control.UIAxes
        ep                          matlab.ui.control.UIAxes
        Task3_Case2Tab              matlab.ui.container.Tab
        KVLabel_2                   matlab.ui.control.Label
        KWLabel_2                   matlab.ui.control.Label
        PowerFactorLabel31_2        matlab.ui.control.Label
        RecievingVoltageLabel31_2   matlab.ui.control.Label
        PowerLabel31_2              matlab.ui.control.Label
        powerf2                     matlab.ui.control.NumericEditField
        rv_2                        matlab.ui.control.NumericEditField
        powerr_2                    matlab.ui.control.NumericEditField
        CALCANDDRAWButton32         matlab.ui.control.Button
        LabelT32                    matlab.ui.control.Label
        epflead_2                   matlab.ui.control.UIAxes
        epflag_2                    matlab.ui.control.UIAxes
        vpflag                      matlab.ui.control.UIAxes
        vpflead                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        resistivity
        l
        diameter
        symmetrical
        area
        r
        dab
        dbc
        dac
        Deq
        R
        L
        C_ph
        XL
        Y
        Z
        A1
        B1
        C1
        D1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Dbc.Visible = 'off';
            app.Dac.Visible = 'off';
            app.D_bcLabel.Visible = 'off';
            app.D_acLabel.Visible = 'off';
            app.mLabel_6.Visible = 'off';
            app.mLabel_4.Visible = 'off';
            app.TL_model_S.Visible = 'off';
            app.TL_model_Pi.Visible = 'off';
            app.TL_model_T.Visible = 'off';
            app.TorPi.Visible = 'off';
            app.ChooseTheModelLabel_2.Visible = 'off';

        end

        % Button pushed function: DOCALCULATIONSButton
        function DOCALCULATIONSButtonPushed(app, event)
            % Get input values from UI components
            app.resistivity = app.rho.Value;
            app.l = app.len.Value * 1000;
            app.diameter = app.d.Value/100;
            app.symmetrical = app.symm_unsymm.Value;
                                                   
            app.area = pi * (app.diameter/2)^2;
            app.r = app.diameter/2;
            
              if app.symmetrical=="Symmetric"
                % Get distance between phases from UI component
                app.dab = app.Dab.Value;
                app.Deq=app.dab;
                % Calculate Resistance, Inductance, and Capacitance per phase
                app.R = 1.1*app.resistivity * app.l / app.area;
                app.L = (2e-7*log(app.Deq/(0.7788*app.r)));
                app.C_ph = (2*pi*8.854e-12/log(app.Deq/app.r));
           
             else
                % Get distances between phases from UI components
             
               app.dab = app.Dab.Value;
               app.dbc = app.Dbc.Value;
               app.dac = app.Dac.Value;
               app.Deq = power(app.dab*app.dbc*app.dac, 1/3);

                % Calculate Resistance, Inductance, and Capacitance per phase
                app.R = 1.1*app.resistivity * app.l / app.area;
                app.L = (2e-7*log(app.Deq/(0.7788*app.r)));
                app.C_ph = (2*pi*8.854e-12/log(app.Deq/app.r));
             end

            % Display results in the UI component
            app.Rtotal.Value = app.R;
            app.L_phase.Value = app.L;
            app.C_phase.Value = app.C_ph;
            
                        
            app.XL=2*pi*50*app.L*app.l;            
            app.Y=complex(0  ,2*pi*50*app.C_ph*app.l); 
            app.Z=complex(app.R ,app.XL);


            if (app.l < 80000) 
                    app.A1=1;
                    app.D1=1;
                    app.B1=app.Z;
                    app.C1=0;
                    
           else
              switch app.TorPi.Value
                case "T"
                    app.A1=1+0.5*app.Z*app.Y;
                    app.D1=1+0.5*app.Z*app.Y;
                    app.B1=app.Z*(1+0.25*app.Z*app.Y);
                    app.C1=app.Y;
                case "Pi"
                    app.A1=1+0.5*app.Z*app.Y;
                    app.D1=1+0.5*app.Z*app.Y;
                    app.B1=app.Z;
                    app.C1=app.Y*(1+0.25*app.Z*app.Y);
              end
           end
           
                    D = app.D1;
                    A = app.A1;
                    B = app.B1;
                    C = app.C1;
                    
            app.AE.Value = evalc('A');
            app.BE.Value = evalc('B');
            app.CE.Value = evalc('C');
            app.DE.Value = evalc('D');
      
        end

        % Value changed function: symm_unsymm
        function symm_unsymmValueChanged(app, event)
                    app.symmetrical = app.symm_unsymm.Value;
                   
                    if app.symmetrical=="Symmetric"
                    app.Dbc.Visible = 'off';
                    app.Dac.Visible = 'off';
                    app.D_bcLabel.Visible = 'off';
                    app.D_acLabel.Visible = 'off';
                    app.mLabel_6.Visible = 'off';
                    app.mLabel_4.Visible = 'off';
                    
                    
                    else 
                    app.Dbc.Visible = 'on';
                    app.Dac.Visible = 'on';
                    app.D_bcLabel.Visible = 'on';
                    app.D_acLabel.Visible = 'on';
                    app.mLabel_6.Visible = 'on';
                    app.mLabel_4.Visible = 'on';
                    end 
            
            
        end

        % Value changed function: len
        function lenValueChanged(app, event)
            value = app.len.Value;
            if value < 80
                app.TL_model_S.Visible = 'on';
                app.TL_model_T.Visible = 'off';
                app.TL_model_Pi.Visible = 'off';
                app.TorPi.Visible = 'off';
                app.ChooseTheModelLabel_2.Visible = 'off';
            else
                app.ChooseTheModelLabel_2.Visible = 'on';
                app.TorPi.Visible = 'on';
                app.TL_model_T.Visible = 'on'; 
                app.TL_model_S.Visible = 'off';
            end
        end

        % Value changed function: TorPi
        function TorPiValueChanged(app, event)
            TPi = app.TorPi.Value;
                switch TPi
                    
                    case "T"
                       app.TL_model_T.Visible = 'on'; 
                       app.TL_model_Pi.Visible = 'off';
                       app.TL_model_S.Visible = 'off';
                    case "Pi"
                       app.TL_model_Pi.Visible = 'on';
                       app.TL_model_T.Visible = 'off';
                       app.TL_model_S.Visible = 'off';
                end
            
        end

        % Button pushed function: CALCANDDRAWButton31
        function CALCANDDRAWButton31Pushed(app, event)

       
          pf = app.powerf.Value ;
          Pr = 0:1000:app.powerr.Value*1000 ;
          
          x = length(Pr) ;
          
          %assign vectors for Ir,Is,Vs,Ps,VR and eff%
          
          Ir = zeros(1,x) ;
          Is = zeros(1,x) ;
          Vs = zeros(1,x) ;
          Ps = zeros(1,x) ;
          VR = zeros(1,x) ;
          Sr = zeros(1,x) ;
          eff = zeros(1,x) ;     
          Vrl = app.rv.Value*1000;
          Vr = ((Vrl) / sqrt(3)) * exp(0);
          
          %Calc the values of Ir,Is,Vs,Ps,VR and eff% 
          
          for j=1:1:x
           Sr(j) = Pr(j) / (pf) * exp(1i * acos(pf));
           Ir(j) = conj(Sr(j) / (3 * Vr));
    
           Vs(j) = app.A1 * Vr + app.B1 * Ir(j);
           Is(j) = app.C1 * Vr + app.D1 * Ir(j);
    
           VR(j) = (abs(Vs(j) / app.A1) - abs (Vr)) / abs (Vr);

           Ps(j) = 3 * abs(Vs(j)) .* abs(Is(j)) .* cos(angle (Vs(j) .* conj(Is(j))));
           eff(j) = (Pr(j) ./ Ps(j)) .* 100;
          end
          
          %plotting the graphs%

          plot(app.ep,Pr,eff);
          plot(app.vp,Pr,VR);
         
                   
        end

        % Button pushed function: CALCANDDRAWButton32
        function CALCANDDRAWButton32Pushed(app, event)


          Pr = app.powerr_2.Value*1000 ;
          pf = app.powerf2.Value:0.001:1
          x = length(pf) ;
          
          Ir1 = zeros(1,x) ;
          Is1 = zeros(1,x) ;
          Vs1 = zeros(1,x) ;
          Ps1 = zeros(1,x) ;
          VR1 = zeros(1,x) ;
          eff1 = zeros(1,x) ;
          Sr1 = zeros(1,x) ;    
          Ir2 = zeros(1,x) ;
          Is2 = zeros(1,x) ;
          Vs2 = zeros(1,x) ;
          Ps2 = zeros(1,x) ;
          VR2 = zeros(1,x) ;
          eff2 = zeros(1,x) ;
          Sr2 = zeros(1,x) ; 
          Vrl1 = app.rv_2.Value*1000;
          Vr = ((Vrl1) / sqrt(3)) * exp(0);


 %Lagging% 
 
           %Calc the values of Ir,Is,Vs,Ps,VR and eff%
           
           
          for i=1:1:x
              Sr1(i) = Pr ./ (pf(i)) .* exp(1i * acos(pf(i)));
              Ir1(i) = conj(Sr1(i) / (3 * Vr));

              Vs1(i) = app.A1 * Vr + app.B1 * Ir1(i);
              Is1(i) = app.C1 * Vr + app.D1 * Ir1(i);
    
              Ps1(i) = 3 * abs(Vs1(i)) .* abs(Is1(i)) .* cos(angle(Vs1(i) .* conj(Is1(i))));
              VR1(i) = ((abs(Vs1(i) / app.A1) - abs(Vr)) / abs(Vr)) * 100;
              eff1(i) = (Pr ./ Ps1(i)) * 100;
          end
      
  %Leading%
  
            %Calc the values of Ir,Is,Vs,Ps,VR and eff%
            
            
           for i=1:1:x
                Sr2(i) = Pr ./ (pf(i)) .* exp(1i * -acos(pf(i)));    
                Ir2(i) = conj(Sr2(i) / (3 * Vr));
    
                Vs2(i) = app.A1 * Vr + app.B1 * Ir2(i);
                Is2(i) = app.C1 * Vr + app.D1 * Ir2(i);

                VR2(i) = ((abs(Vs2(i) / app.A1) - abs(Vr)) / abs(Vr)) * 100;
                Ps2(i) = 3 * abs(Vs2(i)) .* abs (Is2(i)) .* cos (angle (Vs2(i) .* conj(Is2(i))));
                eff2(i) = (Pr ./ Ps2(i)) * 100;                
            end
          
            
           %Plotting the results%

          plot(app.epflag_2,pf,eff1);            %Lagging% 
          plot(app.vpflag,pf,VR1);             %Lagging% 
          plot(app.epflead_2,pf,eff2);           %Leading%
          plot(app.vpflead,pf,VR2);            %Leading%


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tasks1and2Tab = uitab(app.TabGroup);
            app.Tasks1and2Tab.Title = 'Tasks 1 and 2';
            app.CALCULATIONSOFRL_phC_phANDTLPARAMETERSABCDLabel = uilabel(app.Tasks1and2Tab);
            app.CALCULATIONSOFRL_phC_phANDTLPARAMETERSABCDLabel.Text = 'CALCULATIONS OF R,L_ph,C_ph AND TL PARAMETERS [A B C D]';
            app.symm_unsymm = uidropdown(app.Tasks1and2Tab);
            app.symm_unsymm.Items = {'Symmetric', 'Unsymmetric'};
            app.symm_unsymm.ValueChangedFcn = createCallbackFcn(app, @symm_unsymmValueChanged, true);
            app.symm_unsymm.Value = 'Symmetric';
            app.ConductorresistivitypLabel = uilabel(app.Tasks1and2Tab);
            app.ConductorresistivitypLabel.Text = 'Conductor resistivity (p) = ';
            app.D_acLabel = uilabel(app.Tasks1and2Tab);
            app.D_acLabel.Text = 'D_ac = ';
            app.ConductorDiameterDLabel = uilabel(app.Tasks1and2Tab);
            app.ConductorDiameterDLabel.Text = 'Conductor Diameter (D) = ';
            app.ConductorLengthLLabel = uilabel(app.Tasks1and2Tab);
            app.ConductorLengthLLabel.Text = 'Conductor Length (L) = ';
            app.EnterthedistancebetweenphasesLabel = uilabel(app.Tasks1and2Tab);
            app.EnterthedistancebetweenphasesLabel.Text = 'Enter the distance between phases';
            app.D_abLabel = uilabel(app.Tasks1and2Tab);
            app.D_abLabel.Text = 'D_ab = ';
            app.D_bcLabel = uilabel(app.Tasks1and2Tab);
            app.D_bcLabel.Text = 'D_bc = ';
            app.EntertheresistivitydiameterandlengthLabel = uilabel(app.Tasks1and2Tab);
            app.EntertheresistivitydiameterandlengthLabel.Text = 'Enter the resistivity,diameter and length';
            app.rho = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.d = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.len = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.len.ValueChangedFcn = createCallbackFcn(app, @lenValueChanged, true);
            app.Dac = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.Dbc = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.Dab = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.mLabel = uilabel(app.Tasks1and2Tab);
            app.mLabel.Text = 'Ω.m';
            app.cmLabel = uilabel(app.Tasks1and2Tab);
            app.cmLabel.Text = 'cm';
            app.kmLabel = uilabel(app.Tasks1and2Tab);
            app.kmLabel.Text = 'km';
            app.mLabel_4 = uilabel(app.Tasks1and2Tab);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.Tasks1and2Tab);
            app.mLabel_5.Text = 'm';
            app.mLabel_6 = uilabel(app.Tasks1and2Tab);
            app.mLabel_6.Text = 'm';
            app.ResultsLabel = uilabel(app.Tasks1and2Tab);
            app.ResultsLabel.Text = 'Results';
            app.ResistanceR_ACLabel = uilabel(app.Tasks1and2Tab);
            app.ResistanceR_ACLabel.Text = 'Resistance (R_AC) = ';
            app.CapacitanceCLabel = uilabel(app.Tasks1and2Tab);
            app.CapacitanceCLabel.Text = 'Capacitance (C) = ';
            app.InductanceLLabel = uilabel(app.Tasks1and2Tab);
            app.InductanceLLabel.Text = 'Inductance (L) = ';
            app.C_phase = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.L_phase = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.Rtotal = uieditfield(app.Tasks1and2Tab, 'numeric');
            app.Label = uilabel(app.Tasks1and2Tab);
            app.Label.Text = 'Ω';
            app.FmLabel = uilabel(app.Tasks1and2Tab);
            app.FmLabel.Text = 'F/m';
            app.HmLabel = uilabel(app.Tasks1and2Tab);
            app.HmLabel.Text = 'H/m';
            app.CalculationsofTLparametersLabel_2 = uilabel(app.Tasks1and2Tab);
            app.CalculationsofTLparametersLabel_2.Text = 'Calculations of TL parameters';
            app.Label_11 = uilabel(app.Tasks1and2Tab);
            app.Label_11.Text = 'Ω';
            app.ChooseTheModelLabel_2 = uilabel(app.Tasks1and2Tab);
            app.ChooseTheModelLabel_2.Text = 'Choose The Model';
            app.TorPi = uidropdown(app.Tasks1and2Tab);
            app.TorPi.Items = {'T', 'Pi'};
            app.TorPi.ValueChangedFcn = createCallbackFcn(app, @TorPiValueChanged, true);
            app.TorPi.Value = 'T';
            app.DOCALCULATIONSButton = uibutton(app.Tasks1and2Tab, 'push');
            app.DOCALCULATIONSButton.ButtonPushedFcn = createCallbackFcn(app, @DOCALCULATIONSButtonPushed, true);
            app.DOCALCULATIONSButton.Text = 'DO CALCULATIONS';
            app.TL_model_S = uilabel(app.Tasks1and2Tab);
            app.TL_model_S.Text = 'Short Model';
            app.TL_model_Pi = uilabel(app.Tasks1and2Tab);
            app.TL_model_Pi.Text = 'Pi Medim Model';
            app.TL_model_T = uilabel(app.Tasks1and2Tab);
            app.TL_model_T.Text = 'T Medium Model';
            app.AE = uieditfield(app.Tasks1and2Tab, 'text');
            app.CE = uieditfield(app.Tasks1and2Tab, 'text');
            app.BE = uieditfield(app.Tasks1and2Tab, 'text');
            app.DE = uieditfield(app.Tasks1and2Tab, 'text');
            app.SiemensLabel = uilabel(app.Tasks1and2Tab);
            app.SiemensLabel.Text = 'Siemens';
            app.Task3_Case1Tab = uitab(app.TabGroup);
            app.Task3_Case1Tab.Title = 'Task3_Case1';
            app.ep = uiaxes(app.Task3_Case1Tab);
            xlabel(app.ep, 'Active Power ')
            ylabel(app.ep, 'Efficiency %')
            zlabel(app.ep, 'Z')
            app.vp = uiaxes(app.Task3_Case1Tab);
            xlabel(app.vp, 'Active Power ')
            ylabel(app.vp, 'Voltage Regulation %')
            zlabel(app.vp, 'Z')
            app.LabelT31 = uilabel(app.Task3_Case1Tab);
            app.LabelT31.Text = 'Case1:';
            app.PowerLabel31 = uilabel(app.Task3_Case1Tab);
            app.PowerLabel31.Text = 'Power = ';
            app.KWLabel = uilabel(app.Task3_Case1Tab);
            app.KWLabel.Text = 'KW';
            app.KVLabel = uilabel(app.Task3_Case1Tab);
            app.KVLabel.Text = 'KV';
            app.RecievingVoltageLabel31 = uilabel(app.Task3_Case1Tab);
            app.RecievingVoltageLabel31.Text = 'Recieving Voltage = ';
            app.PowerFactorLabel31 = uilabel(app.Task3_Case1Tab);
            app.PowerFactorLabel31.Text = 'Power Factor = ';
            app.powerr = uieditfield(app.Task3_Case1Tab, 'numeric');
            app.powerr.Value = 100;
            app.powerf = uieditfield(app.Task3_Case1Tab, 'numeric');
            app.powerf.Value = 0.8;
            app.rv = uieditfield(app.Task3_Case1Tab, 'numeric');
            app.CALCANDDRAWButton31 = uibutton(app.Task3_Case1Tab, 'push');
            app.CALCANDDRAWButton31.ButtonPushedFcn = createCallbackFcn(app, @CALCANDDRAWButton31Pushed, true);
            app.CALCANDDRAWButton31.Text = 'CALC AND DRAW';
            app.Task3_Case2Tab = uitab(app.TabGroup);
            app.Task3_Case2Tab.Title = 'Task3_Case2';
            app.vpflead = uiaxes(app.Task3_Case2Tab);
            xlabel(app.vpflead, 'Power Factor Lead')
            ylabel(app.vpflead, 'Voltage Regulation %')
            zlabel(app.vpflead, 'Z')
            app.vpflag = uiaxes(app.Task3_Case2Tab);
            xlabel(app.vpflag, 'Power Factor Lag')
            ylabel(app.vpflag, 'Voltage Regulation %')
            zlabel(app.vpflag, 'Z')
            app.epflag_2 = uiaxes(app.Task3_Case2Tab);
            xlabel(app.epflag_2, 'Power Factor Lag')
            ylabel(app.epflag_2, 'Efficiency %')
            zlabel(app.epflag_2, 'Z')
            app.epflead_2 = uiaxes(app.Task3_Case2Tab);
            xlabel(app.epflead_2, 'Power Factor Lead')
            ylabel(app.epflead_2, 'Efficiency %')
            zlabel(app.epflead_2, 'Z')
            app.LabelT32 = uilabel(app.Task3_Case2Tab);
            app.LabelT32.Text = 'Case2:';
            app.CALCANDDRAWButton32 = uibutton(app.Task3_Case2Tab, 'push');
            app.CALCANDDRAWButton32.ButtonPushedFcn = createCallbackFcn(app, @CALCANDDRAWButton32Pushed, true);
            app.CALCANDDRAWButton32.Text = 'CALC AND DRAW';
            app.powerr_2 = uieditfield(app.Task3_Case2Tab, 'numeric');
            app.powerr_2.Value = 100;
            app.rv_2 = uieditfield(app.Task3_Case2Tab, 'numeric');
            app.powerf2 = uieditfield(app.Task3_Case2Tab, 'numeric');
            app.powerf2.Value = 0.3;
            app.PowerLabel31_2 = uilabel(app.Task3_Case2Tab);
            app.PowerLabel31_2.Text = 'Power = ';
            app.RecievingVoltageLabel31_2 = uilabel(app.Task3_Case2Tab);
            app.RecievingVoltageLabel31_2.Text = 'Recieving Voltage = ';
            app.PowerFactorLabel31_2 = uilabel(app.Task3_Case2Tab);
            app.PowerFactorLabel31_2.Text = 'Power Factor = ';
            app.KWLabel_2 = uilabel(app.Task3_Case2Tab);
            app.KWLabel_2.Text = 'KW';
            app.KVLabel_2 = uilabel(app.Task3_Case2Tab);
            app.KVLabel_2.Text = 'KV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment_finall2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end